classdef RoadNoiseCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        correctionEditField            matlab.ui.control.NumericEditField
        correctionEditFieldLabel       matlab.ui.control.Label
        Calculate2Button               matlab.ui.control.Button
        amountofHGVsEditField_2        matlab.ui.control.NumericEditField
        amountofHGVsEditField_2Label   matlab.ui.control.Label
        VehiclespeedEditField          matlab.ui.control.NumericEditField
        VehiclespeedEditFieldLabel     matlab.ui.control.Label
        DbLabel_2                      matlab.ui.control.Label
        LA1018hEditField               matlab.ui.control.NumericEditField
        LA1018hEditFieldLabel          matlab.ui.control.Label
        DbLabel                        matlab.ui.control.Label
        LA101hLabel                    matlab.ui.control.Label
        LA101hEditField                matlab.ui.control.NumericEditField
        LA101hLabel_2                  matlab.ui.control.Label
        Calculate1Button               matlab.ui.control.Button
        NumberofCarsEditField          matlab.ui.control.NumericEditField
        NumberofCarsEditFieldLabel     matlab.ui.control.Label
        MinutesCountingEditField       matlab.ui.control.NumericEditField
        MinutesCountingEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate1Button
        function Calculate1ButtonPushed(app, event)
            CarInput = app.NumberofCarsEditField.Value;

            MinInput = app.MinutesCountingEditField.Value;
            HrCalc = 60/MinInput;

            CarPerHr = CarInput*HrCalc;
          
            LA101H = 42.2 + 10 * log10(CarPerHr);
            
            app.LA101hEditField.Value = LA101H;
         
            LA1018H = 29.1 + 10 * log10(18 * CarPerHr)

            app.LA1018hEditField.Value = LA1018H;


        end

        % Callback function: not associated with a component
        function CorrectionCalcButtonPushed(app, event)
            V = app.VehiclespeedEditField.Value; 
            P = app.amountofHGVsEditField_2.Value;

            % speed and HGV Corrections
            
            correction = 33 * log10(V + 40 + 500 / V) + 10 * log10(1 + (5 * P / V)) - 68.8;

        end

        % Callback function: not associated with a component
        function correctionButtonPushed(app, event)
            
        end

        % Button pushed function: Calculate2Button
        function correctionButtonPushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinutesCountingEditFieldLabel = uilabel(app.UIFigure);
            app.MinutesCountingEditFieldLabel.Text = 'Minutes Counting';
            app.MinutesCountingEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCarsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCarsEditFieldLabel.Text = 'Number of Cars';
            app.NumberofCarsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Calculate1Button = uibutton(app.UIFigure, 'push');
            app.Calculate1Button.ButtonPushedFcn = createCallbackFcn(app, @Calculate1ButtonPushed, true);
            app.Calculate1Button.Text = 'Calculate 1';
            app.LA101hLabel_2 = uilabel(app.UIFigure);
            app.LA101hLabel_2.Text = 'LA10, 1h';
            app.LA101hEditField = uieditfield(app.UIFigure, 'numeric');
            app.LA101hLabel = uilabel(app.UIFigure);
            app.LA101hLabel.Text = 'LA10, 1h';
            app.DbLabel = uilabel(app.UIFigure);
            app.DbLabel.Text = 'Db';
            app.LA1018hEditFieldLabel = uilabel(app.UIFigure);
            app.LA1018hEditFieldLabel.Text = 'LA10,18h';
            app.LA1018hEditField = uieditfield(app.UIFigure, 'numeric');
            app.DbLabel_2 = uilabel(app.UIFigure);
            app.DbLabel_2.Text = 'Db';
            app.VehiclespeedEditFieldLabel = uilabel(app.UIFigure);
            app.VehiclespeedEditFieldLabel.Text = 'Vehicle speed';
            app.VehiclespeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.amountofHGVsEditField_2Label = uilabel(app.UIFigure);
            app.amountofHGVsEditField_2Label.Text = 'amount of HGVs';
            app.amountofHGVsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Calculate2Button = uibutton(app.UIFigure, 'push');
            app.Calculate2Button.ButtonPushedFcn = createCallbackFcn(app, @correctionButtonPushed2, true);
            app.Calculate2Button.Text = 'Calculate 2';
            app.correctionEditFieldLabel = uilabel(app.UIFigure);
            app.correctionEditFieldLabel.Text = 'correction ';
            app.correctionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoadNoiseCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end